classdef error_est < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ErrorestimationUIFigure  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        aEditFieldLabel          matlab.ui.control.Label
        aEditField               matlab.ui.control.NumericEditField
        bEditFieldLabel          matlab.ui.control.Label
        bEditField               matlab.ui.control.NumericEditField
        EditFieldLabel           matlab.ui.control.Label
        epsEditField             matlab.ui.control.NumericEditField
        epsEditFieldLabel_2      matlab.ui.control.Label
        pEditFieldLabel          matlab.ui.control.Label
        p0EditField              matlab.ui.control.NumericEditField
        p0EditFieldLabel_2       matlab.ui.control.Label
        pointsTextAreaLabel      matlab.ui.control.Label
        pointsTextArea           matlab.ui.control.TextArea
        runButton                matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        pAxes                    matlab.ui.control.UIAxes
        xAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        lines = containers.Map({'points_bBound', 'points_abLine', 'p_e_im'}, {[], [], []});
        %   line handle. It's necessary to be able to
        %   delete a line with "HandleVisibility off"
        
        prec = 3;  
        %   round precision for print
        
        Np = 1000;
        %   number of points to draw on p(e)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            runButtonPushed(app, []);
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            x = cellfun(@str2num, app.pointsTextArea.Value);
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            p0 = app.p0EditField.Value;
            e_min = app.epsEditField.Value;
            disp(event);
            
            [~, app.lines] = main_proc(a, b, x, p0, e_min, app.Np, app.prec, app.pAxes, app.xAxes, app.lines);
            xlim(app.xAxes, [a, b]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ErrorestimationUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ErrorestimationUIFigure = uifigure('Visible', 'off');
            app.ErrorestimationUIFigure.Name = 'Error estimation';
            app.ErrorestimationUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ErrorestimationUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.aEditFieldLabel = uilabel(app.LeftPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.LeftPanel, 'numeric');
            app.aEditField.Value = 0.2;
            app.bEditFieldLabel = uilabel(app.LeftPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.LeftPanel, 'numeric');
            app.bEditField.Value = 0.5;
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Text = 'ε';
            app.epsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.epsEditField.Value = 0.02;
            app.epsEditFieldLabel_2 = uilabel(app.LeftPanel);
            app.epsEditFieldLabel_2.Text = 'min';
            app.pEditFieldLabel = uilabel(app.LeftPanel);
            app.pEditFieldLabel.Text = 'p';
            app.p0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.p0EditField.Value = 0.95;
            app.p0EditFieldLabel_2 = uilabel(app.LeftPanel);
            app.p0EditFieldLabel_2.Text = '0';
            app.pointsTextAreaLabel = uilabel(app.LeftPanel);
            app.pointsTextAreaLabel.Text = 'points';
            app.pointsTextArea = uitextarea(app.LeftPanel);
            app.pointsTextArea.Value = {'0.43'; '0.4'; '0.35'; '0.3'; '0.28'};
            app.runButton = uibutton(app.LeftPanel, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.RightPanel = uipanel(app.GridLayout);
            app.pAxes = uiaxes(app.RightPanel);
            title(app.pAxes, 'p(ε)')
            xlabel(app.pAxes, 'ε')
            ylabel(app.pAxes, 'p')
            app.pAxes.XScale = 'log';
            app.xAxes = uiaxes(app.RightPanel);
            title(app.xAxes, '')
            xlabel(app.xAxes, '')
            ylabel(app.xAxes, '')
            app.xAxes.YTick = [];
            app.xAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.ErrorestimationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = error_est

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ErrorestimationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ErrorestimationUIFigure)
        end
    end
end