classdef error_est_20_04_09_1stOK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        aEditFieldLabel      matlab.ui.control.Label
        aEditField           matlab.ui.control.NumericEditField
        bEditFieldLabel      matlab.ui.control.Label
        bEditField           matlab.ui.control.NumericEditField
        EditFieldLabel       matlab.ui.control.Label
        epsEditField         matlab.ui.control.NumericEditField
        epsEditFieldLabel_2  matlab.ui.control.Label
        pEditFieldLabel      matlab.ui.control.Label
        p0EditField          matlab.ui.control.NumericEditField
        p0EditFieldLabel_2   matlab.ui.control.Label
        pointsTextAreaLabel  matlab.ui.control.Label
        pointsTextArea       matlab.ui.control.TextArea
        runButton            matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
        pAxes                matlab.ui.control.UIAxes
        xAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        e_draw_im_line = [];  %   line handle. It's necessary to be able to
        % delete a line with "HandleVisibility off"
        prec = 3;  %   round precision for print
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            x = cellfun(@str2num, app.pointsTextArea.Value);
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            p0 = app.p0EditField.Value;
            e_min = app.epsEditField.Value;
                        
            %x = reshape(x, [numel(x), 1]);
            e0 = find_eOpt(a, b, x, p0);
            min_dist = min([abs(x - a); abs(x - b)]);

            Np = 100;
            e_draw = exp(linspace(log(e_min), log((b-a)/2), Np)');
            e_draw_im = exp(linspace(log(min(e0, e_min)) - 0.2, log(e_min), Np)');
            p_draw_im = p_fnc(a, b, x, e_draw_im);    
            p_draw = p_fnc(a, b, x, e_draw);          
            
            plot(app.xAxes, [1, 1] * a, [1, -1], 'HandleVisibility', 'off', 'Color', 'black', 'LineWidth', 2);
            plot(app.xAxes, [1, 1] * b, [1, -1], 'HandleVisibility', 'off', 'Color', 'black', 'LineWidth', 2);
            plot(app.xAxes, [a, b], [0, 0], 'Color', 'black', 'HandleVisibility', 'off', 'LineWidth', 2);
            plot(app.xAxes, x, x*0, 'o', 'HandleVisibility', 'off', 'MarkerSize', 10, 'LineWidth', 2, 'Color', 'red');
            xlim(app.xAxes, [a*0.9, b*1.1]);
                    
            cla(app.pAxes);
            delete(app.e_draw_im_line);
            pLeg = legend(app.pAxes);
            set(pLeg, 'FontSize', 16);
            set(pLeg, 'Location', 'best'); 
            set(pLeg, 'Interpreter', 'latex');
            hold(app.pAxes, 'on');
            plot(app.pAxes, e_draw, p_draw,...
                'DisplayName', '$p(\varepsilon)$',...
                'Color', getMyColor(1));
            app.e_draw_im_line = plot(app.pAxes, e_draw_im, p_draw_im, '--',...
                                'HandleVisibility', 'off', 'Color', getMyColor(1));
            plot(app.pAxes, [1, 1] * e_min, [0, 1],...
                 'DisplayName', ['$\sigma_{min} = ' num2str(round(e_min, app.prec)) '$'],...
                 'LineWidth', 2, 'Color', 'red');
            plot(app.pAxes, [1, 1] * min_dist, [0, 1], '--',...
                'DisplayName', ['$d_{min} = ' num2str(round(min_dist, app.prec)) '$'],...
                'Color', getMyColor(5));
            plot(app.pAxes, [min([e_draw; e_draw_im]), e0], [1, 1] * p0,...
                 '--', 'DisplayName', ['$p_0 = ' num2str(round(p0, app.prec)) '$'],...
                 'Color', 'blue');
            plot(app.pAxes, [1, 1] * e0, [0, p0], '--',...
                'DisplayName', ['$\varepsilon_{opt} = ' num2str(round(e0, app.prec)) '$'],...
                'Color', 'blue', 'LineWidth', 2);            
            xlim(app.pAxes, [min([e_draw; e_draw_im]), abs((b-a)/2)]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.aEditFieldLabel = uilabel(app.LeftPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.LeftPanel, 'numeric');
            app.aEditField.Value = 0.3;
            app.bEditFieldLabel = uilabel(app.LeftPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.LeftPanel, 'numeric');
            app.bEditField.Value = 0.5;
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Text = 'ε';
            app.epsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.epsEditField.Value = 0.02;
            app.epsEditFieldLabel_2 = uilabel(app.LeftPanel);
            app.epsEditFieldLabel_2.Text = 'min';
            app.pEditFieldLabel = uilabel(app.LeftPanel);
            app.pEditFieldLabel.Text = 'p';
            app.p0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.p0EditField.Value = 0.8;
            app.p0EditFieldLabel_2 = uilabel(app.LeftPanel);
            app.p0EditFieldLabel_2.Text = '0';
            app.pointsTextAreaLabel = uilabel(app.LeftPanel);
            app.pointsTextAreaLabel.Text = 'points';
            app.pointsTextArea = uitextarea(app.LeftPanel);
            app.pointsTextArea.Value = {'0.35'; '0.4'; '0.45'; '0.33'; '0.46'};
            app.runButton = uibutton(app.LeftPanel, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.RightPanel = uipanel(app.GridLayout);
            app.pAxes = uiaxes(app.RightPanel);
            title(app.pAxes, 'p(ε)')
            xlabel(app.pAxes, 'ε')
            ylabel(app.pAxes, 'p')
            app.pAxes.XScale = 'log';
            app.xAxes = uiaxes(app.RightPanel);
            title(app.xAxes, '')
            xlabel(app.xAxes, '')
            ylabel(app.xAxes, '')
            app.xAxes.YTick = [];
            app.xAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = error_est_20_04_09_1stOK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end